classdef demoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        InitRestartButton            matlab.ui.control.Button
        DOMAINLabel                  matlab.ui.control.Label
        nogenerationsEditFieldLabel  matlab.ui.control.Label
        nogenerationsEditField       matlab.ui.control.NumericEditField
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        p % Algorithm configuration
        d % Domain configuration
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            DOMAIN = 'dropwave'; %npoly dropwave shubert4
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            
            ALGORITHM = 'vElites';
            rmpath('mapElites'); rmpath('vElites'); addpath(ALGORITHM);
            % Load configuration of domain and V-Elites
            app.d = domain;
            app.p = defaultParamSet(4);
            app.nogenerationsEditField.Value = app.p.nGens;
            app.DOMAINLabel.Text = ['DOMAIN: ' DOMAIN];
            
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            
            sobSequence         = scramble(sobolset(app.d.dof,'Skip',1e3),'MatousekAffineOwen');
            sobPoint            = 1;
            initSamples         = range(app.d.ranges).*sobSequence(sobPoint:(sobPoint+app.p.numInitSamples)-1,:)+app.d.ranges(1);
            [fitness, values]   = app.d.fitfun(initSamples);
            obsMap              = createMap(app.d.dof, app.p.maxBins, app.p.competeDistance, app.p.infReplacement);
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, values, obsMap, app.d);
            obsMap = updateMap(replaced,replacement,obsMap,fitness,initSamples,features);
            
            [map, ~, ~, ~, allMaps] = vElites(app.d.fitfun,obsMap,app.p,app.d);
            
            % Show data
            viewMap(map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            addpath(genpath('.'));
            DOMAIN = value;
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            ALGORITHM = 'vElites';
            rmpath('mapElites'); rmpath('vElites'); addpath(ALGORITHM);            
            app.d = domain;
            app.DOMAINLabel.Text = ['DOMAIN: ' DOMAIN];            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InitRestartButton = uibutton(app.UIFigure, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Text = 'Init/Restart';
            app.DOMAINLabel = uilabel(app.UIFigure);
            app.DOMAINLabel.Text = 'DOMAIN';
            app.nogenerationsEditFieldLabel = uilabel(app.UIFigure);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.nogenerationsEditField.Limits = [1 10000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'dropwave', 'shubert4', 'npoly'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'dropwave';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demoGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end